classdef Vocales < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        VozagudaButton                matlab.ui.control.Button
        VozgraveButton                matlab.ui.control.Button
        Grabeunaudiode3segundosLabel  matlab.ui.control.Label
        IntroduzcaunarchivodeaudioLabel  matlab.ui.control.Label
        CargararchivodeaudioButton    matlab.ui.control.Button
        ArchivoEditField              matlab.ui.control.EditField
        NombredelarchivoLabel         matlab.ui.control.Label
        IntroduzcaunaudioLabel        matlab.ui.control.Label
        IdentificacindevocalesLabel   matlab.ui.control.Label
        VocalLabel                    matlab.ui.control.Label
        subtituloLabel                matlab.ui.control.Label
        AnalizaraudioButton           matlab.ui.control.Button
        ReproduciraudioButton         matlab.ui.control.Button
        GrabarunaudioButton           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Audio_vec    % Description
        frecuencia   % Frecuencia de la señal obtenida
        tiempo       % Duranción del audio a grabar
        tam          % Elemento necesario para la ventana de Hamming
        
        reduced
        
        fft_vec
        fft_x
        
        formantes
    end
    
    methods (Access = private)
        
        function GraficarAudioXY(app, x, y)
            cla(app.UIAxes, 'reset');
            xline(app.UIAxes, 0);
            plot(app.UIAxes, x, y); 
            grid(app.UIAxes, 'on');
            title(app.UIAxes,'Audio');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
        end
        
        function GraficarAudioY(app)
            cla(app.UIAxes, 'reset');
            xline(app.UIAxes, 0);
            plot(app.UIAxes, app.Audio_vec); 
            grid(app.UIAxes, 'on');
            title(app.UIAxes,'Audio');
            xlabel(app.UIAxes,'n'); 
            ylabel(app.UIAxes,'F(n)'); 
        end
        
        function AudioGrabado_Interactivity(app)
            app.ReproduciraudioButton.Visible = 'on';
            app.AnalizaraudioButton.Visible = 'on';
        end
        
        function AudioAnalizado_Interactivity(app)
            app.VozgraveButton.Visible = 'on';
            app.VozagudaButton.Visible = 'on';
        end
        
        function prediccion_Interactivity(app)
            app.subtituloLabel.Visible = 'on';
            app.VocalLabel.Visible = 'on';
        end
        
        function Hide_Buttons(app)
            app.ReproduciraudioButton.Visible = 'off';
            app.AnalizaraudioButton.Visible = 'off';
            app.VozgraveButton.Visible = 'off';
            app.VozagudaButton.Visible = 'off';
            app.subtituloLabel.Visible = 'off';
            app.VocalLabel.Visible = 'off';
        end
        
        function obtenerFormantes(app)
            %Parte 1: Caro
            %Aplicamos un filtro pasa altos
            preemph = [1 0.63]; 
            x1 = filter(1,preemph,app.reduced);
            %Parte 2: Diana
            %Calculamos los coeficientes de prediccion lineal
            A = lpc(x1,12); 
            rts = roots(A);
            %Parte 3: Ada
            %Conservamos solo las raices que tengan imaginarios positivos y
            %sacamos los angulos de estos
            rts = rts(imag(rts)>=0); 
            angz = atan2(imag(rts),real(rts));
            %Parte 4: David
            %Convertimos las frecuencias angulares en rad/muestra 
            %representadas por los ángulos a Hz
            %Calculamos los anchos de banda de los formantes
            [frqs,indices] = sort(angz.*(app.frecuencia/(2*pi))); 
            bw = -1/2*(app.frecuencia/(2*pi))*log(abs(rts(indices)));
            %Parte 5: Axel 
            indice = 1; 
            for elem = 1:length(frqs)     
                if (frqs(elem) > 90 && bw(elem) <400)         
                    formants(indice) = frqs(elem);         
                    indice = indice+1;     
                end 
            end 
            app.formantes = formants;
            disp(formants);
        end
        
        function compararFormantesH(app)
            
            fundamental = max(app.fft_vec);
            
            if fundamental > 100
                vah = [746.6, 1465];
                veh = [450.9, 1200];
                %0.3860    1.2483
                vih = [415.5, 1769];
                %274.2634  965.2346
                voh = [444.9, 769.2];
                %439.3448  943.4293
                vuh = [313.8, 1400];
                %0.3850    1.4066
            else
                vah = [746.6, 1465];
                veh = [523.9, 2000];
                %0.4972    2.0437
                vih = [415.5, 2000];
                %0.3540    2.0824
                voh = [444.9, 769.2];
                vuh = [313.8, 685.6];
                %372.5411  632.7464
            end
            
            vocal = [app.formantes(1),app.formantes(2)]
            
            distah = norm(vah-vocal);
            disteh = norm(veh-vocal);
            distih = norm(vih-vocal);
            distoh = norm(voh-vocal);
            distuh = norm(vuh-vocal);
            
            aux = [distah,disteh,distih,distoh,distuh];
            
            pred = min(aux);
            
            switch pred
                case distah    
                    app.VocalLabel.Text = 'A';
                case disteh    
                    app.VocalLabel.Text = 'E';
                case distih    
                    app.VocalLabel.Text = 'I';
                case distoh    
                    app.VocalLabel.Text = 'O';
                case distuh    
                    app.VocalLabel.Text = 'U';
            end
            
        end
        
        function compararFormantesF(app)
            
            fundamental = max(app.fft_vec);
            
            if fundamental > 650
                vaf = [1400, 3000]; 
                vef = [800.867, 3500];
                vif = [2500, 6000]; 
                vof = [ 700,  5500];
                vuf = [ 680,  4400];
            else
                vaf = [1200, 4300]; 
                vef = [630.867, 4200]; 
                vif = [2500, 6000];
                vof = [ 850,  4600]; 
                vuf = [ 688.8,  4400]; 
            end


            vocal = [app.formantes(1),app.formantes(2)]
            
            distaf = norm(vaf-vocal);
            distef = norm(vef-vocal);
            distif = norm(vif-vocal);
            distof = norm(vof-vocal);
            distuf = norm(vuf-vocal);
            
            aux = [distaf,distef,distif,distof,distuf];
            
            pred = min(aux);
            
            disp(max(app.fft_vec));
            
            switch pred
                case distaf    
                    app.VocalLabel.Text = 'A';
                case distef    
                    app.VocalLabel.Text = 'E';
                case distif    
                    app.VocalLabel.Text = 'I';
                case distof    
                    app.VocalLabel.Text = 'O';
                case distuf    
                    app.VocalLabel.Text = 'U';
            end
            
        end
        
        function [Y] = FastFourierT(app, A)
            n = length(A);
            if n==1
                Y = [A(1)];
            else
                w = [];
                for k=0:n-1
                    alpha = (-2*pi*k)./n;
                    w = [w, (cos(alpha)+sin(alpha)*i)];
                end
                A0 = [];
                A1 = [];
                for k=0:(n/2)-1
                    A0 = [A0, A(k*2+1)];
                    A1 = [A1, A(k*2+2)];
                end
                Y0 = FastFourierT(app, A0);
                Y1 = FastFourierT(app, A1);
        
                Y = [];
                for k=1:length(Y0)
                    Y = [Y, Y0(k)+w(k)*Y1(k)];
                end
                for k=1:length(Y0)
                    Y = [Y, Y0(k)-w(k)*Y1(k)];
                end
            end
        end
        
        function Transformada(app)
            
            Fs = app.frecuencia;           %f de muestreo
            Ts = 1/Fs;          %Periodo de muestreo
            L = app.tam;           %Muestras
            t = (0:L-1).*Ts;    %vector tiempo
            app.reduced = app.Audio_vec.*hamming(app.tam); %Señal con funcion hamming aplicada
            
            disp("REDUCIDO:");
            disp(app.reduced);
            
            %%Espectro de frecuencias
            [y] = FastFourierT(app, app.reduced);
            P1 = 2.*(abs(y(1:L/2)/2)); %Valores positivos no imaginarios de amplitud original
            
            f = Fs.*(0:(L/2)-1)./L;    %Vector de frecuencias
            
            app.fft_vec = P1;
            app.fft_x = f;
            
            GraficarAudioXY(app, f, P1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GrabarunaudioButton
        function GrabarunaudioButtonPushed(app, event)
            app.frecuencia = 10000;
            app.tiempo = 3;
            app.tam = app.frecuencia*app.tiempo;
            
            Hide_Buttons(app)
            cla(app.UIAxes,'reset');
            % Graba audio por 3 segundos
            recObj = audiorecorder(app.frecuencia, 16, 1);
            disp('Inicio de la grabación')
            app.GrabarunaudioButton.Text = "Grabando audio...";
            recordblocking(recObj, app.tiempo);
            disp('Fin de la grabación');
           
            % Guarda los datos obtenidos en un vector
            app.Audio_vec = getaudiodata(recObj);
            % Grafica el vector obtenido del audio
            %plot(app.UIAxes, app.Audio_vec);
            GraficarAudioY(app);
            app.GrabarunaudioButton.Text = "Grabar un audio";
            AudioGrabado_Interactivity(app);
        end

        % Button pushed function: ReproduciraudioButton
        function ReproduciraudioButtonPushed(app, event)
             % Reproduce la grabación del audio introducido.
             sound(app.Audio_vec, app.frecuencia);
        end

        % Button pushed function: CargararchivodeaudioButton
        function CargararchivodeaudioButtonPushed(app, event)
            Hide_Buttons(app);
            %Carga el nombre del archivo del audio
            filename = app.ArchivoEditField.Value;
            
            % Guarda el audio y los canales
            [app.Audio_vec,app.frecuencia] = audioread(filename);
            
            app.Audio_vec = app.Audio_vec(:,1);
            
            N = length(app.Audio_vec); % sample lenth
            app.tiempo = N/app.frecuencia; % total time span of audio signal
            
            app.tam = app.frecuencia*app.tiempo;
            
            % Grafica el vector obtenido del audio
            %plot(app.UIAxes, app.Audio_vec);
            GraficarAudioY(app);
            AudioGrabado_Interactivity(app);
        end

        % Button pushed function: AnalizaraudioButton
        function AnalizaraudioButtonPushed(app, event)
            Transformada(app);
            AudioAnalizado_Interactivity(app);
        end

        % Button pushed function: VozgraveButton
        function VozgraveButtonPushed(app, event)
            obtenerFormantes(app);
            compararFormantesH(app);
            prediccion_Interactivity(app);
        end

        % Button pushed function: VozagudaButton
        function VozagudaButtonPushed(app, event)
            obtenerFormantes(app);
            compararFormantesF(app);
            prediccion_Interactivity(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.GrabarunaudioButton = uibutton(app.UIFigure, 'push');
            app.GrabarunaudioButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarunaudioButtonPushed, true);
            app.GrabarunaudioButton.Text = 'Grabar un audio';
            app.ReproduciraudioButton = uibutton(app.UIFigure, 'push');
            app.ReproduciraudioButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciraudioButtonPushed, true);
            app.ReproduciraudioButton.Visible = 'off';
            app.ReproduciraudioButton.Text = 'Reproducir audio';
            app.AnalizaraudioButton = uibutton(app.UIFigure, 'push');
            app.AnalizaraudioButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizaraudioButtonPushed, true);
            app.AnalizaraudioButton.Visible = 'off';
            app.AnalizaraudioButton.Text = 'Analizar audio';
            app.subtituloLabel = uilabel(app.UIFigure);
            app.subtituloLabel.Visible = 'off';
            app.subtituloLabel.Text = 'La vocal que usted dijo es: ';
            app.VocalLabel = uilabel(app.UIFigure);
            app.VocalLabel.Visible = 'off';
            app.VocalLabel.Text = 'X';
            app.IdentificacindevocalesLabel = uilabel(app.UIFigure);
            app.IdentificacindevocalesLabel.Text = 'Identificación de vocales';
            app.IntroduzcaunaudioLabel = uilabel(app.UIFigure);
            app.IntroduzcaunaudioLabel.Text = 'Introduzca un audio';
            app.NombredelarchivoLabel = uilabel(app.UIFigure);
            app.NombredelarchivoLabel.Text = 'Nombre del archivo:';
            app.ArchivoEditField = uieditfield(app.UIFigure, 'text');
            app.CargararchivodeaudioButton = uibutton(app.UIFigure, 'push');
            app.CargararchivodeaudioButton.ButtonPushedFcn = createCallbackFcn(app, @CargararchivodeaudioButtonPushed, true);
            app.CargararchivodeaudioButton.Text = 'Cargar archivo de audio';
            app.IntroduzcaunarchivodeaudioLabel = uilabel(app.UIFigure);
            app.IntroduzcaunarchivodeaudioLabel.Text = 'Introduzca un archivo de audio:';
            app.Grabeunaudiode3segundosLabel = uilabel(app.UIFigure);
            app.Grabeunaudiode3segundosLabel.Text = 'Grabe un audio de 3 segundos:';
            app.VozgraveButton = uibutton(app.UIFigure, 'push');
            app.VozgraveButton.ButtonPushedFcn = createCallbackFcn(app, @VozgraveButtonPushed, true);
            app.VozgraveButton.Visible = 'off';
            app.VozgraveButton.Text = 'Voz grave';
            app.VozagudaButton = uibutton(app.UIFigure, 'push');
            app.VozagudaButton.ButtonPushedFcn = createCallbackFcn(app, @VozagudaButtonPushed, true);
            app.VozagudaButton.Visible = 'off';
            app.VozagudaButton.Text = 'Voz aguda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Vocales

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end